classdef SBTimer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Timer                        matlab.ui.Figure
        Tabs                         matlab.ui.container.TabGroup
        Create                       matlab.ui.container.Tab
        Selecter                     matlab.ui.control.Button
        CurrentFolderEditFieldLabel  matlab.ui.control.Label
        CurrentFolder                matlab.ui.control.EditField
        Explanation                  matlab.ui.control.Label
        TotalBuildersEditFieldLabel  matlab.ui.control.Label
        TotalBuilders                matlab.ui.control.NumericEditField
        EditBuilderLabel             matlab.ui.control.Label
        SelectBuilder                matlab.ui.control.DropDown
        EditBuilderPanel             matlab.ui.container.Panel
        BuilderNameEditFieldLabel    matlab.ui.control.Label
        BuilderNameEdit              matlab.ui.control.EditField
        SplitsTable                  matlab.ui.control.Table
        AddAbove                     matlab.ui.control.Button
        AddBelow                     matlab.ui.control.Button
        Delete                       matlab.ui.control.Button
        BuilderDescriptionTextAreaLabel  matlab.ui.control.Label
        builderDescription           matlab.ui.control.TextArea
        LoadREADMEButton             matlab.ui.control.Button
        AllTimersTab                 matlab.ui.container.Tab
        AllTimers                    matlab.ui.control.Table
        SAVEButton                   matlab.ui.control.Button
        YourtimingsetwillbecreatedinEditFieldLabel  matlab.ui.control.Label
        saveLocation                 matlab.ui.control.EditField
        SequenceDescriptionusemarkdownformattingLabel  matlab.ui.control.Label
        seqDesc                      matlab.ui.control.TextArea
        PushtoGithubButton           matlab.ui.control.Button
        RequiresmanualloginthroughMATLABcommandwindowLabel  matlab.ui.control.Label
        SequenceNameEditFieldLabel   matlab.ui.control.Label
        SeqName                      matlab.ui.control.EditField
        SplitsTab                    matlab.ui.container.Tab
    end


    properties (Access = private)
        readmeLocal % readme file location
        readmeFID %FID for readme
        createBuilderNum % what num is active builder?
        path %path to folder
        createInd %last selected splits creation cell
        builderDesc = {'' '' '' ''}; %builder descriptions
    end

    properties (Access = public)
        Splits = {cell(1) cell(1) cell(1) cell(1)}; % Cell of cells of splits to do 
    end

    methods (Access = private)
    
        function [] = ReaderManip(app, state)
            %ReaderManip Mess around with the stuff in readme
            if(strcmp(state, 'read'))
                cd(app.path); %need to be local
                fid = fopen([app.path 'README.md'], 'r');
                line = fgetl(fid);
                header = strsplit(line, '# ');
                app.SeqName.Value = header{end};
                fgetl(fid);%skip over the double spaces
                line = fgetl(fid);
                passed = 0; %flag for description
                desc = [];
                numBuilders = 1; %which spot is it at
                while all(line ~= -1) || isempty(line) %loop to get data
                    if(strcmp(line, '### Below are the builder and splits settings'))
                        passed = 1;
                        line = fgetl(fid);
                        continue; %no need to do stuff with this line
                    end %if below line
                    if(passed == 0)
                        if(isempty(desc))
                            desc = line; %no line before first line
                        else
                            desc = [desc newline line]; %concat thingy
                        end
                    else
                        if(~isempty(line)) %skip over double spaces
                            parts = strsplit(line,': '); %get buildername
                            builderName{numBuilders} = parts{1};
                            splits = strsplit(parts{2},', '); %get splits
                            app.Splits{numBuilders} = splits; %store into cell
                            numBuilders = numBuilders+1; %increment
                        end %if
                    end %if passed
                    line = fgetl(fid);
                end %while loop
                app.TotalBuilders.Value = numBuilders-1; %set total and account for last round
                app.Splits = app.Splits(1:numBuilders-1); %only the new stuff
                app.SelectBuilder.Items = builderName; %assign to dropdown
                app.SelectBuilder.Value = builderName{1}; %set to first
                app.BuilderNameEdit.Value = app.SelectBuilder.Value; %assignment
                app.SplitsTable.Data = app.Splits{1}'; %set to first
                app.seqDesc.Value = desc;
                previewSplits(app);
            elseif(strcmp(state, 'write'))
                fid = fopen([app.path 'README.md'], 'w');
                fprintf(fid, '# %s', app.SeqName.Value); %title
                fprintf(fid, newline); fprintf(fid, newline); %spaces
                sequenceDescription = textBoxes(app,app.seqDesc.Value);
                fprintf(fid, sequenceDescription);
                fprintf(fid, newline); fprintf(fid, newline);
                fprintf(fid, '### Below are the builder and splits settings'); %separator
                fprintf(fid, newline); fprintf(fid, newline);
                for indB = 1:app.TotalBuilders.Value %for every builder
                    fprintf(fid, '%s: ',app.SelectBuilder.Items{indB});
                    for indSp = 1:numel(app.Splits{indB})
                        if(indSp == numel(app.Splits{indB}))%last thing, no comaa
                            fprintf(fid, app.Splits{indB}{indSp});
                            fprintf(fid, newline); fprintf(fid, newline);
                        else
                            fprintf(fid, '%s, ',app.Splits{indB}{indSp});
                        end %if
                    end %for splits
                end
                
                for indEx = 1:app.TotalBuilders.Value %for every builder
                    filename = sprintf('%s.md',app.SelectBuilder.Items{indEx});
                    fidS = fopen(filename,'w'); %write from beginning
                    header = [{'Date' 'Time'} app.Splits{indEx} {'Total Time' 'Notes'}];
                    fprintf(fidS,'# %s Splits \n\n',app.SelectBuilder.Items{indEx});
                    fprintf(fidS,'%s\n\n',app.builderDesc{indEx}); %print description
                    fprintf(fidS,'### Below are the splits and times\n\n|'); %separator
                    loops = numel(header); %how many times
                    sep = '|'; %start separator line
                    for indSpli = 1:loops
                        fprintf(fidS,'%s|',header{indSpli}); %print header part
                        sep = [sep '---|']; %make separator
                    end %for splits printing
                    fprintf(fidS, newline);
                    fprintf(fidS,sep); %print separator
                end
%                 !git add 
%                 !git commit -m 'Adding readme'
            elseif(strcmp(state, 'time'))
                
            end %if state
        end
        
        function [] = previewSplits(app)
            %previewSplits Update the table with all the split information
            app.AllTimers.ColumnName = app.SelectBuilder.Items; %cols
            totalRows = max(cellfun(@numel,app.Splits)); %how many rows in total
            data = cell(totalRows,app.TotalBuilders.Value); %pre allocate
            for indCol = 1:app.TotalBuilders.Value %for every column
                if(numel(app.Splits{indCol}) ~= totalRows) %too few
                    data(:,indCol) = [app.Splits{indCol} cell(1,totalRows - ...
                        numel(app.Splits{indCol}))]';
                else
                    data(:,indCol) = app.Splits{indCol}'; %gotta transpose
                end %if
            end %for
            app.AllTimers.Data = data;
                        
        end
        
        function [paragraph] = textBoxes(app,rawCells)
           %texBoxes Get text from Text Areas out of cells
           total = numel(rawCells); %how many cells
           paragraph = rawCells{1};%initial cell value
           if(total>1)
               for indRow = 2:total
                   paragraph = [paragraph newline rawCells{indRow}];
               end %for rows
           end %if more than 1
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BuilderNameEdit.Value = app.SelectBuilder.Value; %assignment
            app.SplitsTable.Data = cell(1);
            app.createBuilderNum = find(contains(app.SelectBuilder.Items, ...
                app.SelectBuilder.Value));
            previewSplits(app); %make preview
        end

        % Button pushed function: Selecter
        function SelecterButtonPushed(app, event)
            [f,app.path] = uiputfile('README.md'); %get file location
            if(f ~= 0)
                cd(app.path); %be in the right spot
                app.readmeLocal = [app.path f]; %concat
                app.readmeFID = fopen(app.readmeLocal, 'w'); %w/ writing
                parts = strsplit(app.path, '/'); %for folder name
                app.CurrentFolder.Value = parts{end-1}; %bottom folder
                app.saveLocation.Value = app.path;
            end %if error
        end

        % Close request function: Timer
        function TimerCloseRequest(app, event)
            fclose all; %be safe fam
            delete(app)
            
        end

        % Value changed function: TotalBuilders
        function TotalBuildersValueChanged(app, event)
            value = app.TotalBuilders.Value;
            opts = app.SelectBuilder.Items;
            existing = numel(opts); %how many already things
            if(existing > value) %more than total builders
                opts = opts(1:value);
                app.Splits = app.Splits(1:value); %manage data
                app.builderDesc = app.builderDesc{1:value};
            elseif(existing < value) %need to add more things
                for indOpts = existing+1:value
                    opts{indOpts} = sprintf('Builder %d',indOpts);
                    app.Splits{indOpts} = cell(1);
                    app.builderDesc{indOpts} = '';
                end %for
            end
            app.SelectBuilder.Items = opts; %put that back in
            previewSplits(app); %update preview
        end

        % Value changed function: SelectBuilder
        function SelectBuilderValueChanged(app, event)
            name = app.SelectBuilder.Value;
            app.BuilderNameEdit.Value = name; %add to name
            app.createBuilderNum = find(contains(app.SelectBuilder.Items, name));
            app.SplitsTable.Data = app.Splits{app.createBuilderNum}';
            app.builderDescription.Value = app.builderDesc{app.createBuilderNum};
        end

        % Value changed function: BuilderNameEdit
        function BuilderNameEditValueChanged(app, event)
            newName = app.BuilderNameEdit.Value;
            app.SelectBuilder.Items{app.createBuilderNum} = newName;
            app.SelectBuilder.Value = newName;
            previewSplits(app); %update preview
        end

        % Button pushed function: LoadREADMEButton
        function LoadREADMEButtonPushed(app, event)
            [f,app.path] = uigetfile('README.md'); %get file location
            if(f ~= 0)
                cd(app.path); %get to the right spot for other things
                app.readmeLocal = [app.path f]; %concat
                app.readmeFID = fopen(app.readmeLocal, 'r'); %w/ reading only
                parts = strsplit(app.path,'/'); %for folder name
                app.CurrentFolder.Value = parts{end-1}; %bottom folder
                app.saveLocation.Value = app.path;
            end %if
         %   try
                ReaderManip(app, 'read');
%             catch help
%                 uialert(app.Timer,'File preset not loaded correctly, please try again','Error');
%             end
        end

        % Button pushed function: AddAbove
        function AddAboveButtonPushed(app, event)
            try
                data = app.SplitsTable.Data; %pull it out
                newRow = cell(1); %to size
                data = [data(1:app.createInd(1)-1)' newRow data(app.createInd(1):end)'];
                app.SplitsTable.Data = data'; %transpose
                app.Splits{app.createBuilderNum} = data; %fill in universal thingy
            catch helper
                uialert(app.Timer, 'Please try reselecting a cell', 'Split Editing');
            end
            previewSplits(app); %update preview
        end

        % Cell selection callback: SplitsTable
        function SplitsTableCellSelection(app, event)
            app.createInd = event.Indices;
        end

        % Button pushed function: AddBelow
        function AddBelowButtonPushed(app, event)
            try
                data = app.SplitsTable.Data; %pull it out
                newRow = cell(1); %to size
                data = [data(1:app.createInd(1))' newRow data(app.createInd(1)+1:end)'];
                app.SplitsTable.Data = data'; %transpose
                app.Splits{app.createBuilderNum} = data; %fill in universal thingy
            catch
                uialert(app.Timer, 'Please try reselecting a cell', 'Split Editing');
            end
            previewSplits(app); %update preview
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            try
                data = app.SplitsTable.Data; %pull it out
                data = [data(1:app.createInd(1)-1)' data(app.createInd(1)+1:end)'];
                app.SplitsTable.Data = data'; %transpose
                app.Splits{app.createBuilderNum} = data; %fill in universal thingy
            catch
                uialert(app.Timer, 'Please try reselecting a cell', 'Split Editing');
            end
            previewSplits(app); %update preview
        end

        % Cell edit callback: SplitsTable
        function SplitsTableCellEdit(app, event)
            app.Splits{app.createBuilderNum} = app.SplitsTable.Data';
            previewSplits(app); %update preview
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            ReaderManip(app, 'write');
        end

        % Value changed function: builderDescription
        function builderDescriptionValueChanged(app, event)
            text = textBoxes(app,app.builderDescription.Value);
            app.builderDesc{app.createBuilderNum} = text;
            %set new value into cell
        end

        % Button pushed function: PushtoGithubButton
        function PushtoGithubButtonPushed(app, event)
            !git add .
            !git commit -m 'Creating new Sequence'
            !git push
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Timer = uifigure;
            app.Timer.Name = 'UI Figure';
            app.Timer.CloseRequestFcn = createCallbackFcn(app, @TimerCloseRequest, true);
            app.Tabs = uitabgroup(app.Timer);
            app.Create = uitab(app.Tabs);
            app.Create.Title = 'Create Timers';
            app.Selecter = uibutton(app.Create, 'push');
            app.Selecter.ButtonPushedFcn = createCallbackFcn(app, @SelecterButtonPushed, true);
            app.Selecter.Text = 'Create README';
            app.CurrentFolderEditFieldLabel = uilabel(app.Create);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder';
            app.CurrentFolder = uieditfield(app.Create, 'text');
            app.CurrentFolder.Editable = 'off';
            app.Explanation = uilabel(app.Create);
            app.Explanation.Text = {'Please create a README file within the folder for your sequence.  Create one folder per '; 'sequence, with the title ''README.md'' as the filename.  Name your folder for the sequence.'; 'Alternatively, load a pre-created README to edit splits.  Re-saving will delete old times!  '; 'To make modifications after recording times, create a new folder!'};
            app.TotalBuildersEditFieldLabel = uilabel(app.Create);
            app.TotalBuildersEditFieldLabel.Text = 'Total Builders';
            app.TotalBuilders = uieditfield(app.Create, 'numeric');
            app.TotalBuilders.Limits = [0 Inf];
            app.TotalBuilders.ValueDisplayFormat = '%.0f';
            app.TotalBuilders.ValueChangedFcn = createCallbackFcn(app, @TotalBuildersValueChanged, true);
            app.TotalBuilders.Value = 4;
            app.EditBuilderLabel = uilabel(app.Create);
            app.EditBuilderLabel.Text = 'Edit Builder:';
            app.SelectBuilder = uidropdown(app.Create);
            app.SelectBuilder.Items = {'Builder 1', 'Builder 2', 'Builder 3', 'Builder 4'};
            app.SelectBuilder.Editable = 'on';
            app.SelectBuilder.ValueChangedFcn = createCallbackFcn(app, @SelectBuilderValueChanged, true);
            app.SelectBuilder.Value = 'Builder 1';
            app.EditBuilderPanel = uipanel(app.Create);
            app.EditBuilderPanel.Title = 'Edit Builder';
            app.BuilderNameEditFieldLabel = uilabel(app.EditBuilderPanel);
            app.BuilderNameEditFieldLabel.Text = 'Builder Name';
            app.BuilderNameEdit = uieditfield(app.EditBuilderPanel, 'text');
            app.BuilderNameEdit.ValueChangedFcn = createCallbackFcn(app, @BuilderNameEditValueChanged, true);
            app.SplitsTable = uitable(app.EditBuilderPanel);
            app.SplitsTable.ColumnName = {'Splits'};
            app.SplitsTable.RowName = {};
            app.SplitsTable.ColumnEditable = true;
            app.SplitsTable.CellEditCallback = createCallbackFcn(app, @SplitsTableCellEdit, true);
            app.SplitsTable.CellSelectionCallback = createCallbackFcn(app, @SplitsTableCellSelection, true);
            app.AddAbove = uibutton(app.EditBuilderPanel, 'push');
            app.AddAbove.ButtonPushedFcn = createCallbackFcn(app, @AddAboveButtonPushed, true);
            app.AddAbove.Text = 'Add Split Above';
            app.AddBelow = uibutton(app.EditBuilderPanel, 'push');
            app.AddBelow.ButtonPushedFcn = createCallbackFcn(app, @AddBelowButtonPushed, true);
            app.AddBelow.Text = 'Add Split Below';
            app.Delete = uibutton(app.EditBuilderPanel, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete Split';
            app.BuilderDescriptionTextAreaLabel = uilabel(app.EditBuilderPanel);
            app.BuilderDescriptionTextAreaLabel.Text = 'Builder Description';
            app.builderDescription = uitextarea(app.EditBuilderPanel);
            app.builderDescription.ValueChangedFcn = createCallbackFcn(app, @builderDescriptionValueChanged, true);
            app.LoadREADMEButton = uibutton(app.Create, 'push');
            app.LoadREADMEButton.ButtonPushedFcn = createCallbackFcn(app, @LoadREADMEButtonPushed, true);
            app.LoadREADMEButton.Text = 'Load README';
            app.AllTimersTab = uitab(app.Tabs);
            app.AllTimersTab.Title = 'All Timers';
            app.AllTimers = uitable(app.AllTimersTab);
            app.AllTimers.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AllTimers.RowName = {};
            app.SAVEButton = uibutton(app.AllTimersTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE!';
            app.YourtimingsetwillbecreatedinEditFieldLabel = uilabel(app.AllTimersTab);
            app.YourtimingsetwillbecreatedinEditFieldLabel.Text = 'Your timing set will be created in:';
            app.saveLocation = uieditfield(app.AllTimersTab, 'text');
            app.saveLocation.Editable = 'off';
            app.SequenceDescriptionusemarkdownformattingLabel = uilabel(app.AllTimersTab);
            app.SequenceDescriptionusemarkdownformattingLabel.Text = {'Sequence '; 'Description '; '(use markdown '; 'formatting)'};
            app.seqDesc = uitextarea(app.AllTimersTab);
            app.PushtoGithubButton = uibutton(app.AllTimersTab, 'push');
            app.PushtoGithubButton.ButtonPushedFcn = createCallbackFcn(app, @PushtoGithubButtonPushed, true);
            app.PushtoGithubButton.Text = 'Push to Github';
            app.RequiresmanualloginthroughMATLABcommandwindowLabel = uilabel(app.AllTimersTab);
            app.RequiresmanualloginthroughMATLABcommandwindowLabel.Text = {'(Requires manual login'; 'through MATLAB '; 'command window)'};
            app.SequenceNameEditFieldLabel = uilabel(app.AllTimersTab);
            app.SequenceNameEditFieldLabel.Text = 'Sequence Name';
            app.SeqName = uieditfield(app.AllTimersTab, 'text');
            app.SplitsTab = uitab(app.Tabs);
            app.SplitsTab.Title = 'Splits';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SBTimer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Timer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Timer)
        end
    end
end